classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SwivelJointArmPanel       matlab.ui.container.Panel
        SerialLamp                matlab.ui.control.Lamp
        SerialcomSwitch           matlab.ui.control.Switch
        SerialcomSwitchLabel      matlab.ui.control.Label
        MovementprogrammingPanel  matlab.ui.container.Panel
        ClearAllLamp              matlab.ui.control.Lamp
        ExecuteLamp               matlab.ui.control.Lamp
        ClearLastLamp             matlab.ui.control.Lamp
        SaveLamp                  matlab.ui.control.Lamp
        ClearlastButton           matlab.ui.control.StateButton
        ClearallButton            matlab.ui.control.StateButton
        ExecuteButton             matlab.ui.control.StateButton
        SaveButton                matlab.ui.control.StateButton
        InversekinematicsPanel    matlab.ui.container.Panel
        PsiLabel                  matlab.ui.control.Label
        ThetaLabel                matlab.ui.control.Label
        PhiLabel                  matlab.ui.control.Label
        InverseMoveButton         matlab.ui.control.StateButton
        XValueEditField           matlab.ui.control.NumericEditField
        YValueEditField           matlab.ui.control.NumericEditField
        ZValueEditField           matlab.ui.control.NumericEditField
        PsiValueEditField         matlab.ui.control.NumericEditField
        ValueEditFieldLabel       matlab.ui.control.Label
        ThetaValueEditField       matlab.ui.control.NumericEditField
        ValueEditField_2Label     matlab.ui.control.Label
        PhiValueEditField         matlab.ui.control.NumericEditField
        ValueEditField_3Label     matlab.ui.control.Label
        ZValueEditFieldLabel      matlab.ui.control.Label
        YValueEditFieldLabel      matlab.ui.control.Label
        XValueEditFieldLabel      matlab.ui.control.Label
        ForwardkinematicsPanel    matlab.ui.container.Panel
        ForwardMoveButton         matlab.ui.control.StateButton
        q6EditField               matlab.ui.control.NumericEditField
        q5EditField               matlab.ui.control.NumericEditField
        q4EditField               matlab.ui.control.NumericEditField
        q3EditField               matlab.ui.control.NumericEditField
        q2EditField               matlab.ui.control.NumericEditField
        q1EditField               matlab.ui.control.NumericEditField
        HomeButton                matlab.ui.control.StateButton
        q1Slider                  matlab.ui.control.Slider
        q2Slider                  matlab.ui.control.Slider
        q3Slider                  matlab.ui.control.Slider
        q4Slider                  matlab.ui.control.Slider
        q5Slider                  matlab.ui.control.Slider
        q6Slider                  matlab.ui.control.Slider
        q6SliderLabel             matlab.ui.control.Label
        q5SliderLabel             matlab.ui.control.Label
        q4SliderLabel             matlab.ui.control.Label
        q3SliderLabel             matlab.ui.control.Label
        q2SliderLabel             matlab.ui.control.Label
        q1SliderLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        a % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = serial('COM3', 'BaudRate', 115200);
            app.SerialLamp.Color='red';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fwrite(app.a,"0,0");
            fclose(app.a);
            delete(app.a);
            delete(app);
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            global q1
            changingValueQ1 = event.Value;
            q1=string(round(changingValueQ1));
            app.q1EditField.Value= changingValueQ1;
            
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            global q2
            changingValueQ2 = event.Value;
            q2=string(round(changingValueQ2));
            app.q2EditField.Value= changingValueQ2;
 
        end

        % Value changed function: SerialcomSwitch
        function SerialcomSwitchValueChanged(app, event)
            if strcmp(app.SerialcomSwitch.Value,'On')
                app.SerialLamp.Color='green';
                fopen(app.a);
                

            elseif strcmp(app.SerialcomSwitch.Value,'Off')
                app.SerialLamp.Color='red';
                fwrite(app.a,"0,0");
                fclose(app.a);
            end   

        end

        % Value changed function: ForwardMoveButton
        function ForwardMoveButtonValueChanged(app, event)
            global q q1 q2 


            q=strcat(q1,',',q2,'\n');
            fwrite(app.a,q);
            
        end

        % Value changed function: q1EditField
        function q1EditFieldValueChanged(app, event)
            global q1
            value = app.q1EditField.Value;
            if value>=170
                value=170;
            elseif value<=-170
                value=-170;
            end
            q1=string(value);
        end

        % Value changed function: HomeButton
        function HomeButtonValueChanged(app, event)
            
            fwrite(app.a,"0,0");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SwivelJointArmPanel = uipanel(app.UIFigure);
            app.SwivelJointArmPanel.Title = 'Swivel Joint Arm';
            app.ForwardkinematicsPanel = uipanel(app.SwivelJointArmPanel);
            app.ForwardkinematicsPanel.Title = 'Forward kinematics';
            app.q1SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q1SliderLabel.Text = 'q1';
            app.q2SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q2SliderLabel.Text = 'q2';
            app.q3SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q3SliderLabel.Text = 'q3';
            app.q4SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q4SliderLabel.Text = 'q4';
            app.q5SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q5SliderLabel.Text = 'q5';
            app.q6SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.ForwardkinematicsPanel);
            app.q6Slider.Limits = [-170 170];
            app.q5Slider = uislider(app.ForwardkinematicsPanel);
            app.q5Slider.Limits = [-170 170];
            app.q4Slider = uislider(app.ForwardkinematicsPanel);
            app.q4Slider.Limits = [-170 170];
            app.q3Slider = uislider(app.ForwardkinematicsPanel);
            app.q3Slider.Limits = [-170 170];
            app.q2Slider = uislider(app.ForwardkinematicsPanel);
            app.q2Slider.Limits = [-170 170];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q1Slider = uislider(app.ForwardkinematicsPanel);
            app.q1Slider.Limits = [-170 170];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.HomeButton = uibutton(app.ForwardkinematicsPanel, 'state');
            app.HomeButton.ValueChangedFcn = createCallbackFcn(app, @HomeButtonValueChanged, true);
            app.HomeButton.Text = 'Home';
            app.q1EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q2EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q3EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q4EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q5EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q6EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.ForwardMoveButton = uibutton(app.ForwardkinematicsPanel, 'state');
            app.ForwardMoveButton.ValueChangedFcn = createCallbackFcn(app, @ForwardMoveButtonValueChanged, true);
            app.ForwardMoveButton.Text = 'Move';
            app.InversekinematicsPanel = uipanel(app.SwivelJointArmPanel);
            app.InversekinematicsPanel.Title = 'Inverse kinematics';
            app.XValueEditFieldLabel = uilabel(app.InversekinematicsPanel);
            app.XValueEditFieldLabel.Text = 'X Value';
            app.YValueEditFieldLabel = uilabel(app.InversekinematicsPanel);
            app.YValueEditFieldLabel.Text = 'Y Value';
            app.ZValueEditFieldLabel = uilabel(app.InversekinematicsPanel);
            app.ZValueEditFieldLabel.Text = 'Z Value';
            app.ValueEditField_3Label = uilabel(app.InversekinematicsPanel);
            app.ValueEditField_3Label.Text = 'φ Value';
            app.PhiValueEditField = uieditfield(app.InversekinematicsPanel, 'numeric');
            app.ValueEditField_2Label = uilabel(app.InversekinematicsPanel);
            app.ValueEditField_2Label.Text = 'θ Value';
            app.ThetaValueEditField = uieditfield(app.InversekinematicsPanel, 'numeric');
            app.ValueEditFieldLabel = uilabel(app.InversekinematicsPanel);
            app.ValueEditFieldLabel.Text = 'ψ Value';
            app.PsiValueEditField = uieditfield(app.InversekinematicsPanel, 'numeric');
            app.ZValueEditField = uieditfield(app.InversekinematicsPanel, 'numeric');
            app.YValueEditField = uieditfield(app.InversekinematicsPanel, 'numeric');
            app.XValueEditField = uieditfield(app.InversekinematicsPanel, 'numeric');
            app.InverseMoveButton = uibutton(app.InversekinematicsPanel, 'state');
            app.InverseMoveButton.Text = 'Move';
            app.PhiLabel = uilabel(app.InversekinematicsPanel);
            app.PhiLabel.Text = ' [−π, π]';
            app.ThetaLabel = uilabel(app.InversekinematicsPanel);
            app.ThetaLabel.Text = ' [0, π]';
            app.PsiLabel = uilabel(app.InversekinematicsPanel);
            app.PsiLabel.Text = ' [−π, π]';
            app.MovementprogrammingPanel = uipanel(app.SwivelJointArmPanel);
            app.MovementprogrammingPanel.Title = 'Movement programming';
            app.SaveButton = uibutton(app.MovementprogrammingPanel, 'state');
            app.SaveButton.Text = 'Save';
            app.ExecuteButton = uibutton(app.MovementprogrammingPanel, 'state');
            app.ExecuteButton.Text = 'Execute';
            app.ClearallButton = uibutton(app.MovementprogrammingPanel, 'state');
            app.ClearallButton.Text = 'Clear all';
            app.ClearlastButton = uibutton(app.MovementprogrammingPanel, 'state');
            app.ClearlastButton.Text = 'Clear last';
            app.SaveLamp = uilamp(app.MovementprogrammingPanel);
            app.ClearLastLamp = uilamp(app.MovementprogrammingPanel);
            app.ExecuteLamp = uilamp(app.MovementprogrammingPanel);
            app.ClearAllLamp = uilamp(app.MovementprogrammingPanel);
            app.SerialcomSwitchLabel = uilabel(app.SwivelJointArmPanel);
            app.SerialcomSwitchLabel.Text = 'Serial com';
            app.SerialcomSwitch = uiswitch(app.SwivelJointArmPanel, 'slider');
            app.SerialcomSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialcomSwitchValueChanged, true);
            app.SerialLamp = uilamp(app.SwivelJointArmPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end