classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SwivelJointArmPanel             matlab.ui.container.Panel
        SerialLamp                      matlab.ui.control.Lamp
        SerialcommunicationSwitch       matlab.ui.control.Switch
        SerialcommunicationSwitchLabel  matlab.ui.control.Label
        ForwardkinematicsPanel          matlab.ui.container.Panel
        ForwardMoveButton               matlab.ui.control.StateButton
        q6EditField                     matlab.ui.control.NumericEditField
        q5EditField                     matlab.ui.control.NumericEditField
        q4EditField                     matlab.ui.control.NumericEditField
        q3EditField                     matlab.ui.control.NumericEditField
        q2EditField                     matlab.ui.control.NumericEditField
        q1EditField                     matlab.ui.control.NumericEditField
        HomeButton                      matlab.ui.control.StateButton
        q1Slider                        matlab.ui.control.Slider
        q2Slider                        matlab.ui.control.Slider
        q3Slider                        matlab.ui.control.Slider
        q4Slider                        matlab.ui.control.Slider
        q5Slider                        matlab.ui.control.Slider
        q6Slider                        matlab.ui.control.Slider
        q6SliderLabel                   matlab.ui.control.Label
        q5SliderLabel                   matlab.ui.control.Label
        q4SliderLabel                   matlab.ui.control.Label
        q3SliderLabel                   matlab.ui.control.Label
        q2SliderLabel                   matlab.ui.control.Label
        q1SliderLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        a % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = serial('COM3', 'BaudRate', 115200);
            app.SerialLamp.Color='red';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fwrite(app.a,"0,0,0,0,0,0,");
            fclose(app.a);
            delete(app.a);
            delete(app);
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            global q1
            changingValueQ1 = event.Value;
            q1=string(round(changingValueQ1));
            app.q1EditField.Value= changingValueQ1;
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            global q2
            changingValueQ2 = event.Value;
            q2=string(round(changingValueQ2));
            app.q2EditField.Value= changingValueQ2;
        end

        % Value changed function: SerialcommunicationSwitch
        function SerialcommunicationSwitchValueChanged(app, event)
            if strcmp(app.SerialcommunicationSwitch.Value,'On')
                app.SerialLamp.Color='green';
                fopen(app.a);
                

            elseif strcmp(app.SerialcommunicationSwitch.Value,'Off')
                app.SerialLamp.Color='red';
                fwrite(app.a,"0,0,0,0,0,0,");
                fclose(app.a);
            end   

        end

        % Value changed function: ForwardMoveButton
        function ForwardMoveButtonValueChanged(app, event)
            global q q1 q2 q3 q4 q5 q6


            q=strcat(q1,',',q2,',',q3,',',q4,',',q5,',',q6,',');
            fwrite(app.a,q);
            
        end

        % Value changed function: q1EditField
        function q1EditFieldValueChanged(app, event)
            global q1
            value = app.q1EditField.Value;
            if value>=170
                value=170;
            elseif value<=-170
                value=-170;
            end
            q1=string(value);
        end

        % Value changed function: HomeButton
        function HomeButtonValueChanged(app, event)
            fwrite(app.a,"0,0,0,0,0,0,");
        end

        % Value changed function: q2EditField
        function q2EditFieldValueChanged(app, event)
            global q2
            value = app.q2EditField.Value;
            if value>=170
                value=170;
            elseif value<=-170
                value=-170;
            end
            q2=string(value);
        end

        % Value changed function: q3EditField
        function q3EditFieldValueChanged(app, event)
            global q3
            value = app.q3EditField.Value;
            if value>=170
                value=170;
            elseif value<=-170
                value=-170;
            end
            q3=string(value);
        end

        % Value changed function: q4EditField
        function q4EditFieldValueChanged(app, event)
            global q4
            value = app.q4EditField.Value;
            if value>=170
                value=170;
            elseif value<=-170
                value=-170;
            end
            q4=string(value);
        end

        % Value changed function: q5EditField
        function q5EditFieldValueChanged(app, event)
            global q5
            value = app.q5EditField.Value;
            if value>=170
                value=170;
            elseif value<=-170
                value=-170;
            end
            q5=string(value);
        end

        % Value changed function: q6EditField
        function q6EditFieldValueChanged(app, event)
            global q6
            value = app.q6EditField.Value;
            if value>=170
                value=170;
            elseif value<=-170
                value=-170;
            end
            q6=string(value);
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            global q3
            changingValueQ3 = event.Value;
            q3=string(round(changingValueQ3));
            app.q3EditField.Value= changingValueQ3;
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            global q4
            changingValueQ4 = event.Value;
            q4=string(round(changingValueQ4));
            app.q4EditField.Value= changingValueQ4;
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            global q5
            changingValueQ5 = event.Value;
            q5=string(round(changingValueQ5));
            app.q5EditField.Value= changingValueQ5;    
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            global q6
            changingValueQ6 = event.Value;
            q6=string(round(changingValueQ6));
            app.q6EditField.Value= changingValueQ6;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SwivelJointArmPanel = uipanel(app.UIFigure);
            app.SwivelJointArmPanel.Title = 'Swivel Joint Arm';
            app.ForwardkinematicsPanel = uipanel(app.SwivelJointArmPanel);
            app.ForwardkinematicsPanel.Title = 'Forward kinematics';
            app.q1SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q1SliderLabel.Text = 'q1';
            app.q2SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q2SliderLabel.Text = 'q2';
            app.q3SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q3SliderLabel.Text = 'q3';
            app.q4SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q4SliderLabel.Text = 'q4';
            app.q5SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q5SliderLabel.Text = 'q5';
            app.q6SliderLabel = uilabel(app.ForwardkinematicsPanel);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.ForwardkinematicsPanel);
            app.q6Slider.Limits = [-170 170];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.q5Slider = uislider(app.ForwardkinematicsPanel);
            app.q5Slider.Limits = [-170 170];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q4Slider = uislider(app.ForwardkinematicsPanel);
            app.q4Slider.Limits = [-170 170];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q3Slider = uislider(app.ForwardkinematicsPanel);
            app.q3Slider.Limits = [-170 170];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q2Slider = uislider(app.ForwardkinematicsPanel);
            app.q2Slider.Limits = [-170 170];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q1Slider = uislider(app.ForwardkinematicsPanel);
            app.q1Slider.Limits = [-170 170];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.HomeButton = uibutton(app.ForwardkinematicsPanel, 'state');
            app.HomeButton.ValueChangedFcn = createCallbackFcn(app, @HomeButtonValueChanged, true);
            app.HomeButton.Text = 'Home';
            app.q1EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q1EditField.ValueChangedFcn = createCallbackFcn(app, @q1EditFieldValueChanged, true);
            app.q2EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q2EditField.ValueChangedFcn = createCallbackFcn(app, @q2EditFieldValueChanged, true);
            app.q3EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q3EditField.ValueChangedFcn = createCallbackFcn(app, @q3EditFieldValueChanged, true);
            app.q4EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q4EditField.ValueChangedFcn = createCallbackFcn(app, @q4EditFieldValueChanged, true);
            app.q5EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q5EditField.ValueChangedFcn = createCallbackFcn(app, @q5EditFieldValueChanged, true);
            app.q6EditField = uieditfield(app.ForwardkinematicsPanel, 'numeric');
            app.q6EditField.ValueChangedFcn = createCallbackFcn(app, @q6EditFieldValueChanged, true);
            app.ForwardMoveButton = uibutton(app.ForwardkinematicsPanel, 'state');
            app.ForwardMoveButton.ValueChangedFcn = createCallbackFcn(app, @ForwardMoveButtonValueChanged, true);
            app.ForwardMoveButton.Text = 'Move';
            app.SerialcommunicationSwitchLabel = uilabel(app.SwivelJointArmPanel);
            app.SerialcommunicationSwitchLabel.Text = 'Serial communication';
            app.SerialcommunicationSwitch = uiswitch(app.SwivelJointArmPanel, 'slider');
            app.SerialcommunicationSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialcommunicationSwitchValueChanged, true);
            app.SerialLamp = uilamp(app.SwivelJointArmPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end